classdef manualSegmentation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        myoFigure           matlab.ui.container.Menu
        gForceFigure        matlab.ui.container.Menu
        videosGestures      matlab.ui.container.Menu
        syncGesture         matlab.ui.container.Menu
        relaxGesture        matlab.ui.container.Menu
        fistGesture         matlab.ui.container.Menu
        openGesture         matlab.ui.container.Menu
        pinchGesture        matlab.ui.container.Menu
        waveInGesture       matlab.ui.container.Menu
        waveOutGesture      matlab.ui.container.Menu
        upG                 matlab.ui.container.Menu
        downG               matlab.ui.container.Menu
        leftG               matlab.ui.container.Menu
        rightG              matlab.ui.container.Menu
        forwardG            matlab.ui.container.Menu
        backG               matlab.ui.container.Menu
        acercaDe            matlab.ui.container.Menu
        contactInformation  matlab.ui.container.Menu
        uipanel3            matlab.ui.container.Panel
        text17              matlab.ui.control.Label
        text15              matlab.ui.control.Label
        text16              matlab.ui.control.Label
        buhoAxes            matlab.ui.control.UIAxes
        escudoAxes          matlab.ui.control.UIAxes
        uipanel2            matlab.ui.container.Panel
        deviceText          matlab.ui.control.Label
        text33              matlab.ui.control.Label
        text10              matlab.ui.control.Label
        kRepText            matlab.ui.control.Label
        text8               matlab.ui.control.Label
        gestoText           matlab.ui.control.Label
        text6               matlab.ui.control.Label
        nombreText          matlab.ui.control.Label
        uipanel1            matlab.ui.container.Panel
        qText               matlab.ui.control.Label
        text14              matlab.ui.control.Label
        goButton            matlab.ui.control.Button
        volverButton        matlab.ui.control.Button
        uibuttongroup1      matlab.ui.container.ButtonGroup
        text30              matlab.ui.control.Label
        text29              matlab.ui.control.Label
        text28              matlab.ui.control.Label
        textSegmentLength   matlab.ui.control.Label
        text26              matlab.ui.control.Label
        sEMG8               matlab.ui.control.Label
        sEMG7               matlab.ui.control.Label
        sEMG6               matlab.ui.control.Label
        sEMG5               matlab.ui.control.Label
        sEMG4               matlab.ui.control.Label
        sEMG3               matlab.ui.control.Label
        sEMG2               matlab.ui.control.Label
        sEMG1               matlab.ui.control.Label
        finText             matlab.ui.control.Label
        inicioText          matlab.ui.control.Label
        axesQwyxz           matlab.ui.control.UIAxes
        axesQuats           matlab.ui.control.UIAxes
        mainAxes            matlab.ui.control.UIAxes
        axes5               matlab.ui.control.UIAxes
        axes6               matlab.ui.control.UIAxes
        axes3               matlab.ui.control.UIAxes
        axes8               matlab.ui.control.UIAxes
        axes4               matlab.ui.control.UIAxes
        axes7               matlab.ui.control.UIAxes
        axes2               matlab.ui.control.UIAxes
        axes1               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function printFinalMessage(app)
            % --------------------------------------------------------------------
            
            str = {'Thank you, you finished the segmentation.'
                'The segmentation information is stored in "matFiles/indicesTodos.mat".'
                };
            uiwait(msgbox(str,'MESSAGE','help'));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function manualSegmentation_OpeningFcn(app, varargin)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            movegui('center');
            
            % - logos
            imgBuho = imread('buhoEPN.png');
            
            imshow(imgBuho, 'Parent', handles.buhoAxes);
            
            imgEscudo = imread('lab.png');
            imshow(imgEscudo,  'Parent', handles.escudoAxes);
            
            % - prealod data
            global kUser kGesture kRep flags
            flags.Stop = false;
            file = load('.\matFiles\contadores.mat');
            kGesture = file.kGesture;
            kRep = file.kRep;
            kUser = file.kUser;
            
            % - initial grhaps
            initPlots(handles);
            
            % Choose default command line output for manualSegmentation
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            drawnow
        end

        % Menu selected function: acercaDe
        function acercaDe_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to acercaDe (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            str = {'Manual segmentation of EMG signals for Hand Gesture Recognition'
                ''
                'Versión 3 reactualizada por Jonathan Zea'
                'Proyecto Reconocimiento de Gestos'
                ''
                'Revisión 1: 16 de agosto de 2019'
                'Revisión 2: 20 de agosto de 2019'
                'Revisión 3: 31 de agosto de 2019'
                'Revisión 4: 17 de febrero de 2021'
                'Revision 5: April 142021 (translation)'
                ''
                'Versión 1 creada por el Ing. Jonathan Zea'
                'Agosto de 2018'};
            msgbox(str,'ABOUT...','help');
        end

        % Menu selected function: backG
        function backG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to backG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('backward');
        end

        % Menu selected function: contactInformation
        function contactInformation_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to contactInformation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            str = {'CONTACT INFORMATION'
                ''
                'In case you detect an error in this software'
                'please report it by email to:'
                ''
                'Subject: HGR - Manual Segmentation Software'
                ''
                'Email address:'
                'laboratorio.ia@epn.edu.ec'
                ''
                'With the screenshot of the error.'
                'Thank you.'};
            
            msgbox(str, 'CONTACT','help');
        end

        % Menu selected function: downG
        function downG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to downG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('down');
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % --- Executes on key press with focus on figure1 and none of its controls.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if(isequal(eventdata.Key,'return') || isequal(eventdata.Key,'space'))
                goButton_Callback(hObject, eventdata, handles);
            end
            
            if(isequal(eventdata.Key,'backspace'))
                volverButton_Callback(hObject, eventdata, handles);
            end
        end

        % Window button motion function: figure1
        function figure1_WindowButtonMotionFcn(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %% movimiento del mouse
            global moverBarraInicio moverBarraFin levantado
            global xi xo kXLength
            global DeviceType
            
            if isequal(get(gca, 'Tag'),'mainAxes')
                eje = gca;
            
                %
                C = eje.CurrentPoint;
                x = C(1,1);
                y = C(1,2);
            
                %
                if x > kXLength
                    x = kXLength - 1;
                end
            
                if x < 0
                    x = 1;
                end
            
                %
                params = getParams();
                emgFreq = params.emgFreq.(DeviceType);
                if levantado
                    if moverBarraInicio
                        if x < xo
                            x = round(x);
                            eje.Children(2).XData = [x x];
                            handles.inicioText.String = num2str(x);
                            xi = x;
            
                            % mover barras other plot!
                            moverBarrasMini(handles, 'xi', xi);
            
                            % segment length
                            handles.textSegmentLength.String = ...
                                [num2str(xo - xi) ' (' num2str((xo - xi)/emgFreq) ' s)'];
                        end
                    end
                    if moverBarraFin
                        if x > xi
                            x = round(x);
                            eje.Children(1).XData = [x x];
                            handles.finText.String = num2str(x);
                            xo = x;
            
                            % mover barras other plot!
                            moverBarrasMini(handles, 'xo', xo);
            
                            % segment length
                            handles.textSegmentLength.String = ...
                                [num2str(xo - xi) ' (' num2str((xo - xi)/emgFreq) ' s)'];
                        end
                    end
                end
            
                %
                %     if (x > 1 && x < kXLength) && (y > 0 && y < handles.mainAxes.YLim(2))
                %         % handles.xPosText.String = x;
                % %         handles.xPosText.String = sprintf('x: %.2f, y:%.2f', x, y);
                %     else
                %         handles.xPosText.String = '';
                %     end
            end
        end

        % Window button up function: figure1
        function figure1_WindowButtonUpFcn(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %% banderas para el reconocimiento del mouse
            global levantado moverBarraInicio moverBarraFin
            levantado = false;
            moverBarraInicio = false;
            moverBarraFin = false;
        end

        % Menu selected function: fistGesture
        function fistGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fistGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('fist');
        end

        % Menu selected function: forwardG
        function forwardG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to forwardG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('forward');
        end

        % Menu selected function: gForceFigure
        function gForceFigure_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to gForceFigure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imagenGForce_App();
        end

        % Button pushed function: goButton
        function goButton_Callback(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%
            params = getParams();
            global DeviceType
            emgFreq = params.emgFreq.(DeviceType);
            global kUser kGesture kRep flags xi xo
            nameGesture = k2gesture(kGesture);
            
            if isequal(nameGesture, 'sync')
                lowerBoundSamples = params.lowerBoundSync*emgFreq;
                upperBoundSamples = params.upperBoundSync*emgFreq;
            else
                lowerBoundSamples = params.lowerBound*emgFreq;
                upperBoundSamples = params.upperBound*emgFreq;
            end
            
            segmentTam = xo - xi;
            if segmentTam <= lowerBoundSamples || segmentTam >= upperBoundSamples
                if segmentTam <= lowerBoundSamples
                    str = ['Segment length is ',...
                        num2str(segmentTam) ' (' num2str(segmentTam/emgFreq) ' s) ',...
                        'must be greater than ' num2str(lowerBoundSamples),...
                        ' (' num2str(lowerBoundSamples/emgFreq) ' s) '];
                else
                    str = ['Segment length is ',...
                        num2str(segmentTam) ' (' num2str(segmentTam/emgFreq) ' s) ',...
                        'must be fewer than ' num2str(upperBoundSamples),...
                        ' (' num2str(upperBoundSamples/emgFreq) ' s) '];
                end
                errordlg(str,'ERROR', 'modal');
            else
                grabarIndices(kUser, kGesture, kRep, xi, xo);
                [kUser, kGesture, kRep] = incrementarIndices(kUser, kGesture, kRep);
                if ~flags.Stop
                    [xi, xo] = setUserData(handles, kUser, kGesture, kRep);
                else
                    % grabo de nuevo! con la flags actualizada
                    grabarIndices(kUser, kGesture, kRep, xi, xo);
                    printFinalMessage(app);
                    delete(gcf);
                end
            end
        end

        % Menu selected function: leftG
        function leftG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to leftG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('left');
        end

        % Menu selected function: myoFigure
        function myoFigure_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to myoFigure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imagenMyo_App();
        end

        % Menu selected function: openGesture
        function openGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to openGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('open');
        end

        % Menu selected function: pinchGesture
        function pinchGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pinchGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('pinch');
        end

        % Menu selected function: relaxGesture
        function relaxGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to relaxGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('relax');
        end

        % Menu selected function: rightG
        function rightG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rightG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('right');
        end

        % Menu selected function: syncGesture
        function syncGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to syncGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('sync');
        end

        % Menu selected function: upG
        function upG_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to upG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('up');
        end

        % Button pushed function: volverButton
        function volverButton_Callback(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %%
            global kUser kGesture kRep xi xo
            
            if kUser == 1 && kGesture == 1 && kRep == 1
                msgbox('Not possible');
            
            else
                [kUser, kGesture, kRep] = bajarIndices(kUser, kGesture, kRep);
            
                [xi, xo] = setUserData(handles, kUser, kGesture, kRep);
            end
        end

        % Menu selected function: waveInGesture
        function waveInGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to waveInGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('waveIn');
        end

        % Menu selected function: waveOutGesture
        function waveOutGesture_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to waveOutGesture (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            playGif_App('waveOut');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MANUAL SEGMENTATION OF EMG SIGNALS';
            app.figure1.WindowButtonUpFcn = createCallbackFcn(app, @figure1_WindowButtonUpFcn, true);
            app.figure1.WindowButtonMotionFcn = createCallbackFcn(app, @figure1_WindowButtonMotionFcn, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.myoFigure = uimenu(app.figure1);
            app.myoFigure.MenuSelectedFcn = createCallbackFcn(app, @myoFigure_Callback, true);
            app.myoFigure.Text = 'Myo Armband';
            app.myoFigure.Tag = 'myoFigure';
            app.gForceFigure = uimenu(app.figure1);
            app.gForceFigure.MenuSelectedFcn = createCallbackFcn(app, @gForceFigure_Callback, true);
            app.gForceFigure.Text = 'GForce Pro';
            app.gForceFigure.Tag = 'gForceFigure';
            app.videosGestures = uimenu(app.figure1);
            app.videosGestures.Text = 'Gestures videos';
            app.videosGestures.Tag = 'videosGestures';
            app.syncGesture = uimenu(app.videosGestures);
            app.syncGesture.MenuSelectedFcn = createCallbackFcn(app, @syncGesture_Callback, true);
            app.syncGesture.Text = 'Sync';
            app.syncGesture.Tag = 'syncGesture';
            app.relaxGesture = uimenu(app.videosGestures);
            app.relaxGesture.MenuSelectedFcn = createCallbackFcn(app, @relaxGesture_Callback, true);
            app.relaxGesture.Text = 'Relax';
            app.relaxGesture.Tag = 'relaxGesture';
            app.fistGesture = uimenu(app.videosGestures);
            app.fistGesture.MenuSelectedFcn = createCallbackFcn(app, @fistGesture_Callback, true);
            app.fistGesture.Text = 'Fist';
            app.fistGesture.Tag = 'fistGesture';
            app.openGesture = uimenu(app.videosGestures);
            app.openGesture.MenuSelectedFcn = createCallbackFcn(app, @openGesture_Callback, true);
            app.openGesture.Text = 'Open';
            app.openGesture.Tag = 'openGesture';
            app.pinchGesture = uimenu(app.videosGestures);
            app.pinchGesture.MenuSelectedFcn = createCallbackFcn(app, @pinchGesture_Callback, true);
            app.pinchGesture.Text = 'Pinch';
            app.pinchGesture.Tag = 'pinchGesture';
            app.waveInGesture = uimenu(app.videosGestures);
            app.waveInGesture.MenuSelectedFcn = createCallbackFcn(app, @waveInGesture_Callback, true);
            app.waveInGesture.Text = 'Wave In';
            app.waveInGesture.Tag = 'waveInGesture';
            app.waveOutGesture = uimenu(app.videosGestures);
            app.waveOutGesture.MenuSelectedFcn = createCallbackFcn(app, @waveOutGesture_Callback, true);
            app.waveOutGesture.Text = 'Wave Out';
            app.waveOutGesture.Tag = 'waveOutGesture';
            app.upG = uimenu(app.videosGestures);
            app.upG.MenuSelectedFcn = createCallbackFcn(app, @upG_Callback, true);
            app.upG.Text = 'Up';
            app.upG.Tag = 'upG';
            app.downG = uimenu(app.videosGestures);
            app.downG.MenuSelectedFcn = createCallbackFcn(app, @downG_Callback, true);
            app.downG.Text = 'Down';
            app.downG.Tag = 'downG';
            app.leftG = uimenu(app.videosGestures);
            app.leftG.MenuSelectedFcn = createCallbackFcn(app, @leftG_Callback, true);
            app.leftG.Text = 'Left';
            app.leftG.Tag = 'leftG';
            app.rightG = uimenu(app.videosGestures);
            app.rightG.MenuSelectedFcn = createCallbackFcn(app, @rightG_Callback, true);
            app.rightG.Text = 'Right';
            app.rightG.Tag = 'rightG';
            app.forwardG = uimenu(app.videosGestures);
            app.forwardG.MenuSelectedFcn = createCallbackFcn(app, @forwardG_Callback, true);
            app.forwardG.Text = 'Forward';
            app.forwardG.Tag = 'forwardG';
            app.backG = uimenu(app.videosGestures);
            app.backG.MenuSelectedFcn = createCallbackFcn(app, @backG_Callback, true);
            app.backG.Text = 'Backward';
            app.backG.Tag = 'backG';
            app.acercaDe = uimenu(app.figure1);
            app.acercaDe.MenuSelectedFcn = createCallbackFcn(app, @acercaDe_Callback, true);
            app.acercaDe.Text = 'About...';
            app.acercaDe.Tag = 'acercaDe';
            app.contactInformation = uimenu(app.figure1);
            app.contactInformation.MenuSelectedFcn = createCallbackFcn(app, @contactInformation_Callback, true);
            app.contactInformation.Text = 'Contact';
            app.contactInformation.Tag = 'contactInformation';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.uipanel1);
            app.axes2.Tag = 'axes2';
            app.axes7 = uiaxes(app.uipanel1);
            app.axes7.Tag = 'axes7';
            app.axes4 = uiaxes(app.uipanel1);
            app.axes4.Tag = 'axes4';
            app.axes8 = uiaxes(app.uipanel1);
            app.axes8.Tag = 'axes8';
            app.axes3 = uiaxes(app.uipanel1);
            app.axes3.Tag = 'axes3';
            app.axes6 = uiaxes(app.uipanel1);
            app.axes6.Tag = 'axes6';
            app.axes5 = uiaxes(app.uipanel1);
            app.axes5.XTickLabel = {'0'; '0.5'; '1'};
            app.axes5.Tag = 'axes5';
            app.mainAxes = uiaxes(app.uipanel1);
            app.mainAxes.XLim = [0 1200];
            app.mainAxes.YLim = [0 256];
            app.mainAxes.Tag = 'mainAxes';
            app.axesQuats = uiaxes(app.uipanel1);
            app.axesQuats.XLim = [0 100];
            app.axesQuats.YLim = [-1 1];
            app.axesQuats.Tag = 'axesQuats';
            app.axesQwyxz = uiaxes(app.uipanel1);
            app.axesQwyxz.Tag = 'axesQwyxz';
            app.inicioText = uilabel(app.uipanel1);
            app.inicioText.Tag = 'inicioText';
            app.inicioText.Text = '400';
            app.finText = uilabel(app.uipanel1);
            app.finText.Tag = 'finText';
            app.finText.Text = '800';
            app.sEMG1 = uilabel(app.uipanel1);
            app.sEMG1.Tag = 'sEMG1';
            app.sEMG1.Text = '1';
            app.sEMG2 = uilabel(app.uipanel1);
            app.sEMG2.Tag = 'sEMG2';
            app.sEMG2.Text = '2';
            app.sEMG3 = uilabel(app.uipanel1);
            app.sEMG3.Tag = 'sEMG3';
            app.sEMG3.Text = '3';
            app.sEMG4 = uilabel(app.uipanel1);
            app.sEMG4.Tag = 'sEMG4';
            app.sEMG4.Text = '4';
            app.sEMG5 = uilabel(app.uipanel1);
            app.sEMG5.Tag = 'sEMG5';
            app.sEMG5.Text = '5';
            app.sEMG6 = uilabel(app.uipanel1);
            app.sEMG6.Tag = 'sEMG6';
            app.sEMG6.Text = '6';
            app.sEMG7 = uilabel(app.uipanel1);
            app.sEMG7.Tag = 'sEMG7';
            app.sEMG7.Text = '7';
            app.sEMG8 = uilabel(app.uipanel1);
            app.sEMG8.Tag = 'sEMG8';
            app.sEMG8.Text = '8';
            app.text26 = uilabel(app.uipanel1);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Segment length:';
            app.textSegmentLength = uilabel(app.uipanel1);
            app.textSegmentLength.Tag = 'textSegmentLength';
            app.textSegmentLength.Text = '';
            app.uibuttongroup1 = uibuttongroup(app.uipanel1);
            app.uibuttongroup1.Title = 'Expected activation level';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.text28 = uilabel(app.uibuttongroup1);
            app.text28.Tag = 'text28';
            app.text28.Text = 'High';
            app.text29 = uilabel(app.uibuttongroup1);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Mid';
            app.text30 = uilabel(app.uibuttongroup1);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Low';
            app.volverButton = uibutton(app.uipanel1, 'push');
            app.volverButton.ButtonPushedFcn = createCallbackFcn(app, @volverButton_Callback, true);
            app.volverButton.Tag = 'volverButton';
            app.volverButton.Text = 'BACK';
            app.goButton = uibutton(app.uipanel1, 'push');
            app.goButton.ButtonPushedFcn = createCallbackFcn(app, @goButton_Callback, true);
            app.goButton.Tag = 'goButton';
            app.goButton.Text = 'CONTINUE';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = {'Press Enter or Space Bar to CONTINUE.'; 'Press Backspace to return BACK.'};
            app.qText = uilabel(app.uipanel1);
            app.qText.Tag = 'qText';
            app.qText.Text = 'Q = w + x*i + y*j + z*k';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Info';
            app.uipanel2.Tag = 'uipanel2';
            app.nombreText = uilabel(app.uipanel2);
            app.nombreText.Tag = 'nombreText';
            app.nombreText.Text = 'pepePancho';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Name:';
            app.gestoText = uilabel(app.uipanel2);
            app.gestoText.Tag = 'gestoText';
            app.gestoText.Text = 'pinch';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Gesture:';
            app.kRepText = uilabel(app.uipanel2);
            app.kRepText.Tag = 'kRepText';
            app.kRepText.Text = '2340453045';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Sample:';
            app.text33 = uilabel(app.uipanel2);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Device:';
            app.deviceText = uilabel(app.uipanel2);
            app.deviceText.Tag = 'deviceText';
            app.deviceText.Text = 'myo';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Tag = 'uipanel3';
            app.escudoAxes = uiaxes(app.uipanel3);
            app.escudoAxes.Tag = 'escudoAxes';
            app.buhoAxes = uiaxes(app.uipanel3);
            app.buhoAxes.Tag = 'buhoAxes';
            app.text16 = uilabel(app.uipanel3);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Hand Gesture Recognition';
            app.text15 = uilabel(app.uipanel3);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Artificial Intelligence and Computer Vision Research Lab';
            app.text17 = uilabel(app.uipanel3);
            app.text17.Tag = 'text17';
            app.text17.Text = 'ESCUELA POLITÉCNICA NACIONAL';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualSegmentation_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)manualSegmentation_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end