classdef seleccionarUsuario_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        aceptarPushbutton       matlab.ui.control.Button
        uipanel1                matlab.ui.container.Panel
        listaDeUsuariosPopMenu  matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function seleccionarUsuario_OpeningFcn(app, varargin)
            % --- Executes just before seleccionarUsuario is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to seleccionarUsuario (see VARARGIN)
            
            % Choose default command line output for seleccionarUsuario
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes seleccionarUsuario wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            movegui(hObject,'center'); % mostrando en el centro
            % Verifica si la carpeta de datos tiene todos los gestos y todas las
            % muestras por gesto completos
            global usuarios;
            [flagCompleteness, usersWithIncompleteness] = checkCompletenessOfSamples();
            if flagCompleteness ~= 1
                str{1} = 'ERROR';
                str{2} = '';
                if flagCompleteness == -1
                    str{3} = 'Following user has incomplete data:';
                else
                    str{3} = 'Following users have incomplete data:';
                end
                str{4} = '';
                numUsersWithErrors = length(usersWithIncompleteness);
                count = 4;
                for i = 1:numUsersWithErrors
                    count = count + 1;
                    str{count} = upper(usersWithIncompleteness{i}.name);
                end
                str{count + 1} = '';
            
                uiwait(errordlg(str, 'ERROR IN DATA'));
                delete(gcf);
                return;
            end
            
            % Verifica si todas las carpetas tienen la fotografía requerida
            [flagCompleteness, usersWithIncompleteness] = checkCompletenessOfPictures();
            if flagCompleteness ~= 1
                str{1} = 'ERROR';
                str{2} = '';
                if length(usersWithIncompleteness) == 1
                    str{3} = 'The following user does not have photography:';
                else
                    str{3} = 'The following users do not have photography:';
                end
                str{4} = '';
                numUsersWithErrors = length(usersWithIncompleteness);
                count = 4;
                for i = 1:numUsersWithErrors
                    count = count + 1;
                    str{count} = upper(usersWithIncompleteness{i}.name);
                end
                str{count + 1} = '';
                str{count + 2} = 'Please, contact lab members about this issue.';
            
                uiwait(errordlg(str, 'ERROR IN DATA'));
                delete(gcf);
                return;
            end
            
            [encabezado, usuarios, num] = crearListaDeUsuarios();
            str = cell(num + 2, 1);
            str{1} = encabezado;
            for i = 2:(num + 1)
                str{i} = usuarios(i - 1).name;
            end
            % str{num + 2} = 'MI NOMBRE NO ESTÁ EN ESTA LISTA';
            set(handles.listaDeUsuariosPopMenu, 'String', str);
        end

        % Button pushed function: aceptarPushbutton
        function aceptarPushbutton_Callback(app, event)
            % --- Executes on button press in aceptarPushbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to aceptarPushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global usuarios;
            valor = get(handles.listaDeUsuariosPopMenu, 'Value');
            if valor == 1 % No se selecciona un nombre
                str = {'Select your name in the list'};
                uiwait(msgbox(str, 'WARNING', 'warning'));
            else
            %     popMenuList = get(handles.listaDeUsuariosPopMenu, 'String');
            
                nameOfSegmentator = usuarios(valor - 1);
                qstring{1} = 'You selected:';
                qstring{2} = '';
                qstring{3} = upper(usuarios(valor - 1).name);
                qstring{4} = '';
                qstring{5} = 'Is it correct?';
                choice = questdlg(qstring,'Info of the segmentator',...
                    'YES','NO','YES');
                if strcmpi(choice,'YES')
                    delete(gcf);
                    qstr = [upper(usuarios(valor - 1).name), ...
                        '. Is this the first time you use this program?'];
                    choice = questdlg(qstr,'Info of the segmentator',...
                    'YES','NO','YES');
                    if strcmpi(choice,'YES')
                        qqstr{1} = 'Reseting counters';
                        qqstr{2} = '';
                        qqstr{3} = 'If you do this, YOU WILL LOSE ALL THE SEGMENTATIONS';
                        qqstr{4} = '';
                        qqstr{5} = 'Do you want to continue?';
                        answer = questdlg(qqstr, ...
                            'Quesion', ...
                            'YES','NO','NO');
                        if strcmpi(answer,'YES')
                            resetContadores();
                        else
                            delete(gcf);
                            return;
                        end
                    end
                    manualSegmentation_App();
                    save('.\matFiles\nameOfSegmentator.mat', 'nameOfSegmentator');
                end
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'LIST OF SEGMENTATORS';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Segmentator name:';
            app.uipanel1.Tag = 'uipanel1';
            app.listaDeUsuariosPopMenu = uidropdown(app.uipanel1);
            app.listaDeUsuariosPopMenu.Items = {};
            app.listaDeUsuariosPopMenu.Tag = 'listaDeUsuariosPopMenu';
            app.listaDeUsuariosPopMenu.Value = {};
            app.aceptarPushbutton = uibutton(app.figure1, 'push');
            app.aceptarPushbutton.ButtonPushedFcn = createCallbackFcn(app, @aceptarPushbutton_Callback, true);
            app.aceptarPushbutton.Tag = 'aceptarPushbutton';
            app.aceptarPushbutton.Text = 'ACCEPT';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seleccionarUsuario_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)seleccionarUsuario_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end